classdef RETO_FINAL_TREN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SeparacinmmEditField          matlab.ui.control.NumericEditField
        SeparacinmmEditFieldLabel     matlab.ui.control.Label
        RadiommEditField              matlab.ui.control.NumericEditField
        RadiommEditFieldLabel         matlab.ui.control.Label
        LongitudcmEditField           matlab.ui.control.NumericEditField
        LongitudcmEditFieldLabel      matlab.ui.control.Label
        GraficarButton                matlab.ui.control.Button
        PolosdeimanesDropDown         matlab.ui.control.DropDown
        PolosdeimanesDropDownLabel    matlab.ui.control.Label
        PolaridaddelapilaButtonGroup  matlab.ui.container.ButtonGroup
        Button_2                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        SentidoButtonGroup            matlab.ui.container.ButtonGroup
        AntihorarioButton             matlab.ui.control.RadioButton
        HorarioButton                 matlab.ui.control.RadioButton
        MovimientoLabel               matlab.ui.control.Label
        FuerzaLabel                   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            cla(app.UIAxes)

            %Constantes
            mu0 = 4 * pi * 1e-7;    
            
            m = 5.31;               % Magnitud del momento dipolar
            Lb = app.LongitudcmEditField.Value/100;   % Longitud de la bobina 
            t = linspace(0,Lb,1000);
            s = app.SeparacinmmEditField.Value/1000;
            R = app.RadiommEditField.Value/1000;
            Lp = 0.042; %Largo de la pila (m)

            %Sentido de la bobina
            horario=app.SentidoButtonGroup.SelectedObject;
           if(horario==app.HorarioButton)
              k = 2 * pi ./ s;
              x_cable = t;
              y_cable = R * cos(k * t);
              z_cable= R * sin(k * t);
           else
               k = -2 * pi / s;
               x_cable = t;
               y_cable = R * cos(k * t);
               z_cable = R * sin(k * t);
           end

           %Polaridad de Pila
           pila_sent = app.PolaridaddelapilaButtonGroup.SelectedObject;
           if (pila_sent == app.Button)
                I = 1;
           else
                I=-1;
           end

           % Asignacion de signos de cada iman
            switch app.PolosdeimanesDropDown.Value
                case '+ +'
                    m1 = m;
                    m2 = m;
                case '- -'
                    m1 = -m;
                    m2 = -m;
                case '+ -'
                    m1 = m;
                    m2 = -m;
                case '- +'
                    m1 = -m;
                    m2 = m;
            end

            %posicion de imanes
            x_centro = Lb / 2;            
            r1 = [x_centro - Lp/2, 0, 0]; 
            r2 = [x_centro + Lp/2, 0, 0]; 

            %graficar la bobina y campo magnetico
           xrange = linspace(0,Lb, 10);
           yrange = linspace(-R, R, 10);
           zrange = linspace(-R, R, 10);
          
           [x, y, z] = meshgrid(xrange, yrange, zrange);
          
          
           R1 = sqrt((x-r1(1)).^2 + (y-r1(2)).^2 + (z-r1(3)).^2);
           R2 = sqrt((x-r2(1)).^2 + (y-r2(2)).^2 + (z-r2(3)).^2);
          
           Bx1 = mu0 * (3 * m1(1) * (x - r1(1)).^2 - m1(1) * R1.^2) ./ (4 * pi * R1.^5);
           By1 = mu0 * 3 * m1(1) * ((x - r1(1)).*(y - r1(2))) ./ (4 * pi * R1.^5);
           Bz1 = mu0 * 3 * m1(1) * ((x - r1(1)).*(z - r1(3))) ./ (4 * pi * R1.^5);
          
           Bx2 = mu0 * (3 * m2(1) * (x - r2(1)).^2 - m2(1) * R2.^2) ./ (4 * pi * R2.^5);
           By2 = mu0 * 3 * m2(1) * ((x - r2(1)).*(y - r2(2))) ./ (4 * pi * R2.^5);
           Bz2 = mu0 * 3 * m2(1) * ((x - r2(1)).*(z - r2(3))) ./ (4 * pi * R2.^5); 
         
           Bx = Bx1 + Bx2;
           By = By1 + By2;
           Bz = Bz1 + Bz2;
          
           Bmag = sqrt(Bx.^2 + By.^2 + Bz.^2);
          
           Bx = Bx./Bmag;
           By = By./Bmag;
           Bz = Bz./Bmag;


           % Posición sobre la bobina 
           rc = @(t) [t; R * cos(k * t); R * sin(k * t)];
           drc = @(t) [1; -R * k * sin(k * t); R * k * cos(k * t)];
            
           B = @(r, mvec, r0) (mu0 / (4*pi)) * ((3 * dot(mvec, r - r0) * (r - r0) - mvec * norm(r - r0)^2) / norm(r - r0)^5);
           Btotal = @(t) B(rc(t), [m1; 0; 0], r1') + B(rc(t), [m2; 0; 0], r2');
           %Ley de Lorentz
           F = I * integral(@(t) cross(drc(t), Btotal(t)), 0, Lb, 'ArrayValued', true); 

           app.FuerzaLabel.Text = ['Fuerza: ', num2str(F(1), '%.3e'), ' N'];
           if F(1) > 1e-12
            app.MovimientoLabel.Text = 'Movimiento: Derecha';
           elseif F(1) < -1e-12
            app.MovimientoLabel.Text = 'Movimiento: Izquierda';
           else
            app.MovimientoLabel.Text = 'Movimiento: No se mueve';
           end


            % Flecha de dirección del tren
            if abs(F(1)) > 1e-12
                x_arrow = Lb / 2;
                y_arrow = 0;
                z_arrow = R + 0.02;
                dx = sign(F(1)) * 0.03;
                dy = 0;
                dz = 0;
            
                hold(app.UIAxes, 'on')  
                quiver3(app.UIAxes, x_arrow, y_arrow, z_arrow, dx, dy, dz,'Color', 'k', 'LineWidth', 3, 'MaxHeadSize', 2)
            end

            %Graficas

           quiver3(app.UIAxes, x, y, z, Bx, By, Bz, 0.5, 'Color', 'b')
           hold(app.UIAxes, 'on')
           plot3(app.UIAxes, x_cable, y_cable, z_cable, 'r', 'LineWidth', 2)
           plot3(app.UIAxes, r1(1), r1(2), r1(3), 'ko', 'MarkerSize', 15, 'MarkerFaceColor', 'g')
           plot3(app.UIAxes, r2(1), r2(2), r2(3), 'ko', 'MarkerSize', 15, 'MarkerFaceColor', 'm')
           grid(app.UIAxes, 'on')
           view(app.UIAxes, 3);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FuerzaLabel = uilabel(app.UIFigure);
            app.FuerzaLabel.Text = 'Fuerza: ';
            app.MovimientoLabel = uilabel(app.UIFigure);
            app.MovimientoLabel.Text = 'Movimiento:';
            app.SentidoButtonGroup = uibuttongroup(app.UIFigure);
            app.SentidoButtonGroup.Title = 'Sentido';
            app.HorarioButton = uiradiobutton(app.SentidoButtonGroup);
            app.HorarioButton.Text = 'Horario';
            app.HorarioButton.Value = true;
            app.AntihorarioButton = uiradiobutton(app.SentidoButtonGroup);
            app.AntihorarioButton.Text = 'Antihorario';
            app.PolaridaddelapilaButtonGroup = uibuttongroup(app.UIFigure);
            app.PolaridaddelapilaButtonGroup.Title = 'Polaridad de la pila';
            app.Button = uiradiobutton(app.PolaridaddelapilaButtonGroup);
            app.Button.Text = '+/-';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.PolaridaddelapilaButtonGroup);
            app.Button_2.Text = '-/+';
            app.PolosdeimanesDropDownLabel = uilabel(app.UIFigure);
            app.PolosdeimanesDropDownLabel.Text = 'Polos de imanes';
            app.PolosdeimanesDropDown = uidropdown(app.UIFigure);
            app.PolosdeimanesDropDown.Items = {'+ +', '- -', '+ -', '- +', ''};
            app.PolosdeimanesDropDown.Value = '+ +';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.LongitudcmEditFieldLabel = uilabel(app.UIFigure);
            app.LongitudcmEditFieldLabel.Text = 'Longitud (cm)';
            app.LongitudcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiommEditFieldLabel = uilabel(app.UIFigure);
            app.RadiommEditFieldLabel.Text = 'Radio (mm)';
            app.RadiommEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeparacinmmEditFieldLabel = uilabel(app.UIFigure);
            app.SeparacinmmEditFieldLabel.Text = 'Separación (mm)';
            app.SeparacinmmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RETO_FINAL_TREN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end